classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel_2                matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        TimelapseButton        matlab.ui.control.Button
        OverlayButton          matlab.ui.control.Button
        SliderButton           matlab.ui.control.Button
        DifferenceButton       matlab.ui.control.Button
        LatestButton           matlab.ui.control.Button
        EarliestButton         matlab.ui.control.Button
        MenuPanel              matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        AnalyzeButton          matlab.ui.control.Button
        LatestDropDown         matlab.ui.control.DropDown
        LatestDropDownLabel    matlab.ui.control.Label
        EarliestDropDown       matlab.ui.control.DropDown
        EarliestDropDownLabel  matlab.ui.control.Label
        ImportFolderButton     matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageFolder          % Folder path selected by user
        ImageList            % Sorted list of image filenames
        AlignedImages        % Aligned/preprocessed image data
        TransformParams      % Registration parameters from preprocessing
        idxEarliest          % Index of the earliest selected image
        idxLatest            % Index of the latest selected image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportFolderButton
        function ImportFolderButtonPushed(app, event)
            % Ask user to select a folder
            folderPath = uigetdir;
            if folderPath == 0
                uialert(app.UIFigure, 'No folder selected.', 'Error');
                return;
            end
            app.ImageFolder = folderPath;

            % Get all image files in the selected folder
            imgFormats = {'*.jpg','*.jpeg','*.png','*.tif','*.bmp'};
            files = [];
            for i = 1:length(imgFormats)
                files = [files; dir(fullfile(folderPath, imgFormats{i}))];
            end

            if isempty(files)
                uialert(app.UIFigure, 'No image files found in the selected folder.', 'Error');
                return;
            end

            % Extract filenames
            fileList = {files.name};

            % Try to sort by naming format (YYYY MM)
            try
                dates = zeros(length(fileList), 2);
                for i = 1:length(fileList)
                    tokens = regexp(fileList{i}, '(\d{4}) (\d{2})', 'tokens');
                    if isempty(tokens)
                        error('Invalid naming format');
                    end
                    dates(i, :) = [str2double(tokens{1}{1}), str2double(tokens{1}{2})];
                end
                [~, sortIdx] = sortrows(dates);
                sortedList = fileList(sortIdx);
            catch
                uialert(app.UIFigure, 'Invalid filename format. Expected "YYYY MM.jpg"', 'Error');
                return;
            end

            % Store sorted list in app properties
            app.ImageList = sortedList;

            % Call the preprocessing module
            [app.AlignedImages, app.TransformParams] = register_images(folderPath, sortedList);

            % Update dropdown menus
            app.EarliestDropDown.Items = sortedList;
            app.LatestDropDown.Items = sortedList;

            % Feedback in command window
            disp('Images successfully imported and preprocessed:');
            disp(sortedList);
        end

        % Value changed function: EarliestDropDown
        function EarliestDropDownValueChanged(app, event)
           app.idxEarliest = find(strcmp(app.ImageList, app.EarliestDropDown.Value));
            
        end

        % Value changed function: LatestDropDown
        function LatestDropDownValueChanged(app, event)
            app.idxLatest = find(strcmp(app.ImageList, app.LatestDropDown.Value));
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            % Check if both earliest and latest images are selected
            if isempty(app.idxEarliest) || isempty(app.idxLatest)
                uialert(app.UIFigure, 'Please select both the earliest and latest images.', 'Error');
                return;
            end

            % Validate that the latest image is after the earliest
            if app.idxLatest <= app.idxEarliest
                uialert(app.UIFigure, 'The latest image must be later than the earliest image.', 'Error');
                return;
            end

            % Display the earliest image as the default view
            imshow(app.AlignedImages{app.idxEarliest}, 'Parent', app.UIAxes)
            axis(app.UIAxes, 'off')

            % TODO: Switch to the analysis/mode selection view
            % You can switch panels here if using a multi-page layout
            disp('Ready to enter the analysis view.')
            disp('AlignedImages count:')
            disp(numel(app.AlignedImages))

            disp('idxEarliest selected:')
            disp(app.idxEarliest)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image Display')
            app.UIAxes.Tag = 'DisplayAxes';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.MenuPanel = uipanel(app.UIFigure);
            app.MenuPanel.Title = 'Menu';
            app.MenuPanel.Tag = 'Menu';
            app.GridLayout2 = uigridlayout(app.MenuPanel);
            app.ImportFolderButton = uibutton(app.GridLayout2, 'push');
            app.ImportFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFolderButtonPushed, true);
            app.ImportFolderButton.Tag = 'ImportButton';
            app.ImportFolderButton.Text = 'Import Folder';
            app.EarliestDropDownLabel = uilabel(app.GridLayout2);
            app.EarliestDropDownLabel.Text = 'Earliest';
            app.EarliestDropDown = uidropdown(app.GridLayout2);
            app.EarliestDropDown.ValueChangedFcn = createCallbackFcn(app, @EarliestDropDownValueChanged, true);
            app.EarliestDropDown.Tag = 'EarliestDropdown';
            app.LatestDropDownLabel = uilabel(app.GridLayout2);
            app.LatestDropDownLabel.Text = 'Latest ';
            app.LatestDropDown = uidropdown(app.GridLayout2);
            app.LatestDropDown.ValueChangedFcn = createCallbackFcn(app, @LatestDropDownValueChanged, true);
            app.LatestDropDown.Tag = 'LatestDropdown';
            app.AnalyzeButton = uibutton(app.GridLayout2, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Tag = 'AnalyzeButton';
            app.AnalyzeButton.Text = 'Analyze';
            app.Panel_2 = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.EarliestButton = uibutton(app.GridLayout3, 'push');
            app.EarliestButton.Tag = 'EarliestButton';
            app.EarliestButton.Text = 'Earliest';
            app.LatestButton = uibutton(app.GridLayout3, 'push');
            app.LatestButton.Tag = 'LatestButton';
            app.LatestButton.Text = 'Latest';
            app.DifferenceButton = uibutton(app.GridLayout3, 'push');
            app.DifferenceButton.Tag = 'DifferenceButton';
            app.DifferenceButton.Text = 'Difference';
            app.SliderButton = uibutton(app.GridLayout3, 'push');
            app.SliderButton.Tag = 'SliderButton';
            app.SliderButton.Text = 'Slider';
            app.OverlayButton = uibutton(app.GridLayout3, 'push');
            app.OverlayButton.Tag = 'OverlayButton';
            app.OverlayButton.Text = 'Overlay';
            app.TimelapseButton = uibutton(app.GridLayout3, 'push');
            app.TimelapseButton.Tag = 'TimelapseButton';
            app.TimelapseButton.Text = 'Time-lapse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end